classdef gui_planner < matlab.apps.AppBase
    %GUI_PLANNER table

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        PanelGenYaml                   matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        MissiondescriptionEditField    matlab.ui.control.EditField
        MissiondescriptionEditFieldLabel  matlab.ui.control.Label
        MissionnameEditField           matlab.ui.control.EditField
        MissionnameEditFieldLabel      matlab.ui.control.Label
        GenerateyamlkmlButton          matlab.ui.control.Button
        PanelSimulation                matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        SpeedsimulationEditField       matlab.ui.control.NumericEditField
        SpeedsimulationEditFieldLabel  matlab.ui.control.Label
        SimulationButton               matlab.ui.control.Button
        PanelPlanner                   matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Switch                         matlab.ui.control.ToggleSwitch
        Lamp                           matlab.ui.control.Lamp
        PlannerButton                  matlab.ui.control.Button
        PanelLoadMap                   matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        LoadmissionButton              matlab.ui.control.Button
        TerrainelevationmodeSwitch     matlab.ui.control.Switch
        TerrainelevationmodeSwitchLabel  matlab.ui.control.Label
        UAVdatabaseEditField           matlab.ui.control.EditField
        UAVdatabaseEditFieldLabel      matlab.ui.control.Label
        PowergridmapEditField          matlab.ui.control.EditField
        PowergridmapEditFieldLabel     matlab.ui.control.Label
        UAVstationsEditField           matlab.ui.control.EditField
        UAVstationsEditFieldLabel      matlab.ui.control.Label
        PanelWeather                   matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        TemperatureCEditField          matlab.ui.control.NumericEditField
        TemperatureCLabel              matlab.ui.control.Label
        WinddirectionEditField         matlab.ui.control.NumericEditField
        WindDirectionLabel             matlab.ui.control.Label
        PressurePaEditField            matlab.ui.control.NumericEditField
        PressurePaEditFieldLabel       matlab.ui.control.Label
        UpdateonlineButton             matlab.ui.control.Button
        WindvelocitymsEditField        matlab.ui.control.NumericEditField
        WindvelocitymsEditFieldLabel   matlab.ui.control.Label
        CleanmissionButton             matlab.ui.control.Button
        InformationTextAreaLabel       matlab.ui.control.Label
        InformationTextArea            matlab.ui.control.TextArea
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        wp; % Waypoints on UTM
        wp_ll; % Waypoints on latitude and longitude
        inspection_height;
        % UAVs Data
        UAVs;
        n_UAVs;
        UAVs_ID; 
        UAVs_model;
        model_UAVs;
        UAVs_vel_nav;
        UAVs_vel_cli;
        UAVs_vel_des;
        wp_horizontal_offset;
        wp_distance_offset;
        wp_gimbal_original;

        % Batteries Data
        bat;
        model_bat ;
        UAVs_q ;
        UAVs_bats_cells;
        UAVs_voltage;
        % Map Data
        towers_pos;
        grid_connections;
        bases_pos;
        bases_names;
        mode_landing;
        mode_landing_name;
        mode_landing_options;
        t_recharging = 1000;
        Pylonsheight = 0;
        UTM_zone;

        % Maximum unbalance (time) between UAVs trajectories [s]:
        delta_max = 1000;

        % Result of plannifier
        tours={};
        totalCost;
        totalBat;

        % Mode of use 
        mode;
        % Colors
        error_col = [ 0.8078    0.6706    0.6706]; % Error color

        % Cluster options
        cluster_flag = 0; 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadmissionButton
        function loadMap(app, event)
            % Add path of functions
            addpath("src\");
            app.UAVs={};

            %% Obtain data from files
            towers_file = app.PowergridmapEditField.Value;
            bases_file = app.UAVstationsEditField.Value;
            UAV_file = app.UAVdatabaseEditField.Value;
            run(extractAfter(UAV_file, '/'));
            app.UAVs_model = UAVs_model;
            app.bat = bats;

            %% Obtain coordinates of bases, towers and connections on UTM [North, East, Elevation]
            [app.towers_pos, app.grid_connections] = kmlTowers(towers_file);
            [app.bases_pos, app.bases_names, app.UTM_zone] = kmlBases(bases_file);  

            %% Update elevation of terrain
            % The tower height is not apply
            if app.TerrainelevationmodeSwitch.Value == "Online"
                app.towers_pos = elevationUpdate(app.towers_pos, app.UTM_zone);
                app.bases_pos = elevationUpdate(app.bases_pos, app.UTM_zone);
            end

            app.mode_landing_options = ["Hover"; "Return to home"; "Auto landing"];

            %% Stablish a first values of parameters of each UAV
            app.n_UAVs=size(app.bases_pos,1);
            app.UAVs = {};
            for i = 1:app.n_UAVs
                app.UAVs{i} = app.UAVs_model{1};
            end
            app.model_UAVs=  repmat(app.UAVs_model{1}.name,1,app.n_UAVs)';
            app.UAVs_vel_nav = 7*ones(app.n_UAVs, 1);
            app.mode_landing_name = repmat(app.mode_landing_options(3),1,app.n_UAVs)';
            app.mode_landing = 2*ones(app.n_UAVs, 1);
            app.UAVs_vel_cli = 5*ones(app.n_UAVs, 1);
            app.UAVs_vel_des = -5*ones(app.n_UAVs, 1);
            app.wp_horizontal_offset = zeros(app.n_UAVs, 1);
            app.inspection_height = 5*ones(app.n_UAVs,1);
            app.wp_distance_offset = 5*ones(app.n_UAVs,1);
            app.wp_gimbal_original = -90*ones(app.n_UAVs, 1);

            app.model_bat = repmat(app.bat{1}.name,1,app.n_UAVs)';
            app.UAVs_q = app.bat{1}.capacity*ones(1, app.n_UAVs);
            app.UAVs_bats_cells = app.bat{1}.cells*ones(1, app.n_UAVs);
            app.UAVs_voltage = app.bat{1}.volt*app.UAVs_bats_cells;
           
            if app.n_UAVs == 1
                app.bases_names = string(app.bases_names);
            end 
            app.UAVs_ID = app.bases_names; 

            tableData = table(app.bases_names,app.model_UAVs,app.UAVs_ID, app.UAVs_vel_nav,app.model_bat, app.mode_landing_name, app.wp_distance_offset, -app.wp_gimbal_original);
            app.UITable.Data = tableData;

            %% Show models of UAVs, batteries and other information on text box
            text = "UAVs: "+char(10);
            for i = 1:length(UAVs_model)
                text = text + char(13) + app.UAVs_model{i}.name +...
                    char(13) + char(9) + "Mass: " + string(app.UAVs_model{i}.m) + " kg" + ...
                    char(13) + char(9) + "Number of rotors: " + string(app.UAVs_model{i}.nr) + ...
                    char(13) + char(9) + "Number of rotor blades: " + string(app.UAVs_model{i}.b) + ...
                    char(13) + char(9) + "Rotor radius: " + string(app.UAVs_model{i}.R) + " m" + ...
                    char(13) + char(9) + "Blade chord (x=0.7*R): " + string(app.UAVs_model{i}.c) + " m" + ...
                    char(13) + char(9) + "Section lift coefficient: " + string(app.UAVs_model{i}.Cl) + ...
                    char(13) + char(9) + "Section draft coefficient: " + string(app.UAVs_model{i}.Cd) + ...
                    char(13) + char(9) + "Induced power factor: " + string(app.UAVs_model{i}.kappa) + ...
                    char(13) + char(9) + "Energy efficiency: " + string(app.UAVs_model{i}.eta) + ...
                    char(13) + char(9) + "P0 numerical constant: " + string(app.UAVs_model{i}.K_mu) + ...
                    char(13) + char(9) + "Equivalent flat-plate area: " + string(app.UAVs_model{i}.f) + " m^2" + ...
                char(13);
            end
            text = text + "_____________"+char(10) + char(10) + "Batteries: " + char (10);
            for i = 1:length(app.bat)
                text = text +char(13) + app.bat{i}.name + ...
                    char(13) + char(9) + "Capacity: " + app.bat{i}.capacity + " mAh" + ...
                    char(13);
            end

            text = text + "_____________" + char(10) + char(10) + "Finish actions:" + char(10);
            for i = 1:length(app.mode_landing_options)
                text = text + char(13)+char(9) + app.mode_landing_options(i);
            end
            %% Update text box
            app.InformationTextArea.Value= text;

            % Recharging time [s]:
            app.t_recharging = 1000;
            % Maximum unbalance (time) between UAVs trajectories [s]:
            app.delta_max = 1000;


            %% Colors
            app.Lamp.Color = [0,1,0];
            app.TemperatureCEditField.BackgroundColor = [1,1,1];
            app.PressurePaEditField.BackgroundColor = [1,1,1];
            pause(0.1);


            %% Draw map
            drawSol(app.UIAxes, app.grid_connections,  app.bases_pos,app.bases_names,  app.n_UAVs, app.towers_pos, [],  [], []);
                

        end

        % Button pushed function: PlannerButton
        function PlannerButtonPushed(app, event)
            if app.PressurePaEditField.Value % If the pressure isn't 0
                %% Colors
                app.Lamp.Color = [1,0,1];
                app.TemperatureCEditField.BackgroundColor = [1,1,1];
                app.PressurePaEditField.BackgroundColor = [1,1,1];
                pause(0.1);
                
                % Working mode
                app.mode  = 0; 

                %% Clean map and draw the new one
                cla(app.UIAxes)
                drawSol(app.UIAxes, app.grid_connections, app.bases_pos, app.bases_names, app.n_UAVs, app.towers_pos, [],  [], []);

                %% Save weather data from GUI
                vel_wind = [app.WindvelocitymsEditField.Value, app.WinddirectionEditField.Value*pi/180];
                humidity = 0;
                temperature = app.TemperatureCEditField.Value;
                pressure = app.PressurePaEditField.Value;
                air_density = airDensity(temperature, humidity, pressure);

                %% Update air_density and gravity of UAVs
                for UAV = 1:app. n_UAVs
                    app.UAVs{UAV}.rho = air_density;    % air density                 [kg/mpress^3]
                    app.UAVs{UAV}.g = 9.81;             % gravity acceleration        [m/s^2]
                end


                %% Plan the tours
                if strcmpi(app.Switch.Value,app.Switch.Items{2})
                    app.cluster_flag = 1;
                else 
                    app.cluster_flag = 0;
                end
                [app.tours, app.totalCost, app.totalBat]=pliPlanner(app.UIAxes, app.towers_pos, app.grid_connections, app.bases_pos,app.bases_names,app.UAVs,app.UAVs_vel_nav,app.UAVs_vel_nav,app.UAVs_vel_cli,app.UAVs_vel_des,app.UAVs_q,app.UAVs_bats_cells,app.UAVs_voltage, app.inspection_height, vel_wind, app.Pylonsheight, app.cluster_flag);

                %% Create the wp for each UAV with the distance from the tower
                %   wp components => [ N, E, terrain height, isGrid, yaw angle, gimbal angle]
                app.wp = separateWP(app.wp_horizontal_offset, app.grid_connections, app.tours, app.towers_pos, app.bases_pos, app.n_UAVs, app.inspection_height, app.Pylonsheight, app.UAVs); %, vel_wind(2), app.UTM_zone);

                %% Draw map with tours
                drawSol(app.UIAxes, app.grid_connections, app.bases_pos, app.bases_names, app.n_UAVs, app.towers_pos,  app.tours, app.wp, []);

                %% Transform wp from UTM to ll
                %   wp_ll components => [ lat, lon, terrain height, isGrid, yaw angle, gimbal angle]
                app.wp_ll = cell(size(app.wp));
                for UAV = 1:app.n_UAVs
                    wp_tour = app.wp{UAV};
                    [wp_latitude, wp_longitude]=utm2ll_fcn(wp_tour(:,1),wp_tour(:,2), app.UTM_zone);
                    app.wp_ll{UAV}=[wp_latitude, wp_longitude, wp_tour(:,3), wp_tour(:,4), wp_tour(:,5), wp_tour(:,6    )];
                end

                %% Recolor lamp
                app.Lamp.Color = [0,1,0];
            else
                if app.HumidityEditField.Value == 0
                    app.HumidityEditField.BackgroundColor = app.error_col;
                else
                    app.HumidityEditField.BackgroundColor = [1,1,1];
                end
                if app.TemperatureCEditField.Value == 0
                    app.TemperatureCEditField.BackgroundColor = app.error_col;
                else
                    app.TemperatureCEditField.BackgroundColor = [1,1,1];
                end
                if app.PressurePaEditField.Value == 0
                    app.PressurePaEditField.BackgroundColor = app.error_col;
                else
                    app.PressurePaEditField.BackgroundColor = [1,1,1];
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.UAVs_ID = app.UITable.Data.Var3; 
            app.UAVs_vel_nav = app.UITable.Data.Var4;
            app.mode_landing_name = app.UITable.Data.Var6;
            UAVs_model_table = app.UITable.Data.Var2;
            bat_table = app.UITable.Data.Var5;
            %% Check if any of the value of the offset have been changed
            

            for UAV = 1:app.n_UAVs
                if app.UITable.Data.Var7(UAV) < 0
                    offset_flag = -1;
                    app.UITable.Data.Var7(UAV) = "###error###";
                elseif app.UITable.Data.Var8(UAV) < 0 | app.UITable.Data.Var8(UAV) > 90
                    offset_flag = -1;
                    app.UITable.Data.Var8(UAV) = "###error###";
                else
                    offset_flag = 1;
                end
                if offset_flag == 1
                    app.wp_distance_offset(UAV) = app.UITable.Data.Var7(UAV);
                    app.wp_gimbal_original(UAV) = -app.UITable.Data.Var8(UAV);
                    app.wp_horizontal_offset(UAV) = app.wp_distance_offset(UAV)*cosd(-app.wp_gimbal_original(UAV));
                    app.inspection_height(UAV) = app.wp_distance_offset(UAV)*sind(-app.wp_gimbal_original(UAV));
                end

                for j = 1:length(app.mode_landing_options)
                    if app.mode_landing_options(j) == app.mode_landing_name(UAV)
                        app.mode_landing(UAV) = j-1;
                        app.UITable.Data.Var6(UAV) = app.mode_landing_name(UAV);
                        break;
                    else
                        app.mode_landing(UAV) = -1;
                        app.UITable.Data.Var6(UAV) = "###error###";
                    end
                end
                % Select UAV models
                for j = 1:length(app.UAVs_model)
                    if app.UAVs_model{j}.name == UAVs_model_table(UAV)
                        app.UAVs{UAV}=app.UAVs_model{j};
                        app.UITable.Data.Var2(UAV) = app.UAVs{UAV}.name;
                        break;
                    else
                        app.UITable.Data.Var2(UAV) = "###error###";
                    end
                end
                % Select battery
                for j=1:length(app.bat)
                    if app.bat{j}.name == bat_table(UAV)
                        app.UITable.Data.Var5(UAV) = app.bat{j}.name;
                        app.UAVs_q(UAV)=app.bat{j}.capacity;
                        app.UAVs_bats_cells(UAV)=app.bat{j}.cells;
                        app.UAVs_voltage(UAV)=app.bat{j}.volt*app.bat{j}.cells;
                        break;
                    else
                        app.UITable.Data.Var5(UAV) = "###error###";
                    end
                end
            end
        end

        % Button pushed function: CleanmissionButton
        function CleanmissionButtonPushed(app, event)
            cla(app.UIAxes)
            app.InformationTextArea.Value= "";
            app.UITable.Data = [];
            app.Lamp.Color = [0,1,0];
            clear all
            %% Colors
            app.Lamp.Color = [0,1,0];
            app.HumidityEditField.BackgroundColor = [1,1,1];
            app.TemperatureCEditField.BackgroundColor = [1,1,1];
            app.PressurePaEditField.BackgroundColor = [1,1,1];
            pause(0.1);
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            simulation(app.UIAxes, app.wp,app.bases_names, app.bases_pos,app.towers_pos,  app.UAVs_vel_nav, app.UAVs_vel_nav, app.UAVs_vel_cli, app.UAVs_vel_des, app.SpeedsimulationEditField.Value,app.n_UAVs, app.grid_connections, app.tours);
        end

        % Button pushed function: GenerateyamlkmlButton
        function GenerateyamlkmlButtonPushed(app, event)
             if ~exist('./mission', 'dir')
                mkdir('./mission');
            end
            kmlGen(app.MissionnameEditField.Value,app.MissiondescriptionEditField.Value, app.wp_ll, app.UAVs_ID);
            yamlGen(app.MissionnameEditField.Value,app.MissiondescriptionEditField.Value, app.mode_landing,app.wp_ll, app.inspection_height, app.bases_pos, app.bases_names, app.wp_horizontal_offset, app.UAVs_vel_nav, app.UAVs, app.UAVs_ID, app.mode);
            bases_ll = app.bases_pos;
            [bases_ll(:,1), bases_ll(:,2)] = utm2ll_fcn(app.bases_pos(:,1), app.bases_pos(:,2), app.UTM_zone); 
            planGen(app.MissionnameEditField.Value,app.MissiondescriptionEditField.Value, app.mode_landing,app.wp_ll, app.inspection_height, bases_ll, app.bases_names, app.UAVs_vel_nav, app.UAVs, app.UAVs_ID); 
        end

        % Button pushed function: UpdateonlineButton
        function UpdateonlineButtonPushed(app, event)
            [app.WindvelocitymsEditField.Value, app.WinddirectionEditField.Value, app.HumidityEditField.Value, app.TemperatureCEditField.Value, app.PressurePaEditField.Value] = weatherApi(app.bases_pos(1,:), app.UTM_zone);
        end

        % Callback function
        function ChangeDirectionButtonPushed(app, event)
            UAV = app.ChangeUAVSpinner.Value;
            if app.n_UAVs >= UAV & length(app.tours) > 0
                app.tours = changeDirection(app.tours, UAV);
                %% Create the wp for each UAV with the distance from the tower
                %   wp components => [ N, E, terrain height, isGrid, yaw angle, gimbal angle]
                app.wp = separateWP(app.wp_horizontal_offset, app.grid_connections, app.tours, app.towers_pos, app.bases_pos, app.n_UAVs, app.inspection_height, app.PylonsheightmEditField.Value, app.UAVs, app.UTM_zone);

                %% Draw map with tours
                drawSol(app.UIAxes, app.grid_connections, app.bases_pos, app.bases_names, app.n_UAVs, app.towers_pos,  app.tours, app.wp, []);

                %% Transform wp from UTM to ll
                %   wp_ll components => [ lat, lon, terrain height, isGrid, yaw angle, gimbal angle]
                app.wp_ll = cell(size(app.wp));
                for UAV = 1:app.n_UAVs
                    wp_tour = app.wp{UAV};
                    [wp_latitude, wp_longitude]=utm2ll_fcn(wp_tour(:,1),wp_tour(:,2), app.UTM_zone);
                    app.wp_ll{UAV}=[wp_latitude, wp_longitude, wp_tour(:,3), wp_tour(:,4), wp_tour(:,5), wp_tour(:,6    )];
                end
            end
        end

        % Callback function
        function Inspection(app, event)
            
            % Working mode
            app.mode  = 1;

            for i = 1:size(app.bases_names,1)
                if strcmp(app.bases_names{i}, app.UAVDropDown.Value)
                    id_base = i; 
                    break; 
                end
            end
            id_tower = app.TowerEditField.Value;
            app.tours = {[0 id_tower id_base; id_tower 0 id_base]}; 
            n_towers = size(app.towers_pos,1); 
            n_points = app.ViewsSpinner.Value + 1; 
            if id_tower> 0 & id_tower <= n_towers
                tower_pos = app.towers_pos(id_tower, :);  
                base_pos = app.bases_pos(id_base,1:2); 
                base_height = app.bases_pos(id_base,3);
                alpha = atan2((tower_pos(2)-base_pos(2)), (tower_pos(1)-base_pos(1)));
                beta = alpha - pi; 
                altitude_inspection = tower_pos(3) + app.PylonsheightmEditField.Value + app.inspection_height(id_base);

                % Create points to inspection
                ang_step = 2*pi /(n_points-1); 
                ang_points = beta:ang_step:beta+2*pi-ang_step; 
                aux = find(ang_points < -pi); 
                ang_points(aux) = ang_points(aux) + 2*pi; 

                aux = find(ang_points > pi); 
                ang_points(aux) = ang_points(aux) - 2*pi; 
                
                ang_points = ang_points'; 

                circle = [app.wp_horizontal_offset(id_base).*[cos(ang_points) sin(ang_points) zeros(size(ang_points))]]; 
                circle = [tower_pos(1:2) altitude_inspection] + circle; 

                yaw = ang_points - pi; 
                aux = find(yaw < -pi); 
                yaw(aux) = yaw(aux) + 2*pi; 

                aux = find(yaw > pi); 
                yaw(aux) = yaw(aux) - 2*pi; 
                
                gimbal = app.wp_gimbal_original(id_base);
                % Ang from last point of circle to base
                gamma = atan2((base_pos(2)-circle(end,2)), (base_pos(1)-circle(end,1)));

                security_altitude = altitude_inspection + 20; 
                app.wp=cell(size(app.bases_pos,1),1); 
                app.wp{1} = [[base_pos base_height + 5], 0, alpha, gimbal;
                             [base_pos security_altitude],0, alpha, gimbal;
                             circle(1,1:2), security_altitude, 0, yaw(1), gimbal;
                             circle, ones(size(ang_points)), yaw, gimbal*ones(size(ang_points));
                             circle(end,1:2), security_altitude, 0, gamma, gimbal;
                             [base_pos security_altitude],0, gamma, gimbal;
                             [base_pos base_height + 5 ],0, gamma, gimbal;
                             ]; 
                app.wp{1}(:,5) = app.wp{1}(:,5)*180/pi; 
                

                %% Transform wp from UTM to ll
                %   wp_ll components => [ lat, lon, terrain height, isGrid, yaw angle, gimbal angle]
                app.wp_ll = cell(size(app.wp));
                for UAV = 1:app.n_UAVs
                    if ~isempty(app.wp{UAV})
                        wp_tour = app.wp{UAV};
                        [wp_latitude, wp_longitude]=utm2ll_fcn(wp_tour(:,1),wp_tour(:,2), app.UTM_zone);
                        app.wp_ll{UAV}=[wp_latitude, wp_longitude, wp_tour(:,3), wp_tour(:,4), wp_tour(:,5), wp_tour(:,6    )];
                    else
                        app.wp_ll{UAV}=[];
                    end
                end


                drawSol(app.UIAxes, app.grid_connections, app.bases_pos, app.bases_names, app.n_UAVs, app.towers_pos,  app.tours, app.wp, []);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Scenario')
            xlabel(app.UIAxes, 'West-East [m]')
            ylabel(app.UIAxes, 'South-North [m]')
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'UAV station'; 'UAV model'; 'UAV ID'; 'Inspection velocity [m/s]'; 'Battery'; 'Finish action'; 'Distance to power grid [m]'; 'Video perspective [º]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.InformationTextArea = uitextarea(app.GridLayout);
            app.InformationTextAreaLabel = uilabel(app.GridLayout);
            app.InformationTextAreaLabel.Text = 'Information';
            app.CleanmissionButton = uibutton(app.GridLayout, 'push');
            app.CleanmissionButton.ButtonPushedFcn = createCallbackFcn(app, @CleanmissionButtonPushed, true);
            app.CleanmissionButton.Text = 'Clean mission';
            app.PanelWeather = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.PanelWeather);
            app.WindvelocitymsEditFieldLabel = uilabel(app.GridLayout2);
            app.WindvelocitymsEditFieldLabel.Text = 'Wind velocity [m/s]';
            app.WindvelocitymsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WindvelocitymsEditField.Value = 1.5;
            app.UpdateonlineButton = uibutton(app.GridLayout2, 'push');
            app.UpdateonlineButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateonlineButtonPushed, true);
            app.UpdateonlineButton.Text = 'Update online';
            app.PressurePaEditFieldLabel = uilabel(app.GridLayout2);
            app.PressurePaEditFieldLabel.Text = 'Pressure [Pa]';
            app.PressurePaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PressurePaEditField.ValueDisplayFormat = '%.1f';
            app.PressurePaEditField.Value = 101325;
            app.WindDirectionLabel = uilabel(app.GridLayout2);
            app.WindDirectionLabel.Text = 'Wind direction [º]';
            app.WinddirectionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WinddirectionEditField.Value = 20;
            app.TemperatureCLabel = uilabel(app.GridLayout2);
            app.TemperatureCLabel.Text = 'Temperature [ºC]';
            app.TemperatureCEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TemperatureCEditField.Value = 25;
            app.PanelLoadMap = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.PanelLoadMap);
            app.UAVstationsEditFieldLabel = uilabel(app.GridLayout3);
            app.UAVstationsEditFieldLabel.Text = 'UAV stations';
            app.UAVstationsEditField = uieditfield(app.GridLayout3, 'text');
            app.UAVstationsEditField.Value = './data/stations.kml';
            app.PowergridmapEditFieldLabel = uilabel(app.GridLayout3);
            app.PowergridmapEditFieldLabel.Text = 'Power-grid map';
            app.PowergridmapEditField = uieditfield(app.GridLayout3, 'text');
            app.PowergridmapEditField.Value = './data/pylons.kml';
            app.UAVdatabaseEditFieldLabel = uilabel(app.GridLayout3);
            app.UAVdatabaseEditFieldLabel.Text = 'UAV database';
            app.UAVdatabaseEditField = uieditfield(app.GridLayout3, 'text');
            app.UAVdatabaseEditField.Value = './data/UAV_database.m';
            app.TerrainelevationmodeSwitchLabel = uilabel(app.GridLayout3);
            app.TerrainelevationmodeSwitchLabel.Text = 'Terrain elevation mode';
            app.TerrainelevationmodeSwitch = uiswitch(app.GridLayout3, 'slider');
            app.TerrainelevationmodeSwitch.Items = {'Offline', 'Online'};
            app.TerrainelevationmodeSwitch.Value = 'Offline';
            app.LoadmissionButton = uibutton(app.GridLayout3, 'push');
            app.LoadmissionButton.ButtonPushedFcn = createCallbackFcn(app, @loadMap, true);
            app.LoadmissionButton.Text = 'Load mission';
            app.PanelPlanner = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.PanelPlanner);
            app.PlannerButton = uibutton(app.GridLayout4, 'push');
            app.PlannerButton.ButtonPushedFcn = createCallbackFcn(app, @PlannerButtonPushed, true);
            app.PlannerButton.Text = 'Planner';
            app.Lamp = uilamp(app.GridLayout4);
            app.Switch = uiswitch(app.GridLayout4, 'toggle');
            app.Switch.Items = {'No clustering', 'Clustering'};
            app.Switch.Value = 'No clustering';
            app.PanelSimulation = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.PanelSimulation);
            app.SimulationButton = uibutton(app.GridLayout5, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.SpeedsimulationEditFieldLabel = uilabel(app.GridLayout5);
            app.SpeedsimulationEditFieldLabel.Text = 'Speed simulation';
            app.SpeedsimulationEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SpeedsimulationEditField.Value = 1;
            app.PanelGenYaml = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.PanelGenYaml);
            app.GenerateyamlkmlButton = uibutton(app.GridLayout6, 'push');
            app.GenerateyamlkmlButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateyamlkmlButtonPushed, true);
            app.GenerateyamlkmlButton.Text = 'Generate yaml & kml';
            app.MissionnameEditFieldLabel = uilabel(app.GridLayout6);
            app.MissionnameEditFieldLabel.Text = 'Mission name';
            app.MissionnameEditField = uieditfield(app.GridLayout6, 'text');
            app.MissionnameEditField.Value = 'mission_1';
            app.MissiondescriptionEditFieldLabel = uilabel(app.GridLayout6);
            app.MissiondescriptionEditFieldLabel.Text = 'Mission description';
            app.MissiondescriptionEditField = uieditfield(app.GridLayout6, 'text');
            app.MissiondescriptionEditField.Value = 'The mission description is here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_planner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end